classdef Scratch_Lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChooseapictureDropDownLabel   matlab.ui.control.Label
        ChooseapictureDropDown        matlab.ui.control.DropDown
        WindowsizeofGaussianKernelSliderLabel  matlab.ui.control.Label
        WindowsizeofGaussianKernelSlider  matlab.ui.control.Slider
        SigmaforGaussianKernelSliderLabel  matlab.ui.control.Label
        SigmaforGaussianKernelSlider  matlab.ui.control.Slider
        rotatethefinalfilterkernelbyagivenangleSliderLabel  matlab.ui.control.Label
        rotatethefinalfilterkernelbyagivenangleSlider  matlab.ui.control.Slider
        LoadimageButton               matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            axis(app.UIAxes, 'image');
            im = imread(app.ChooseapictureDropDown.Value);
            imshow(im, 'parent', app.UIAxes);
        end

        % Value changed function: ChooseapictureDropDown
        function ChooseapictureDropDownValueChanged(app, event)
            disp(app.ChooseapictureDropDown.Value)
            
            if (app.ChooseapictureDropDown.Value == "corn.tif")
                im = imread(app.ChooseapictureDropDown.Value,2);
            else
                im = imread(app.ChooseapictureDropDown.Value);
            end
            imshow(im, 'parent', app.UIAxes);
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            picType = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [nameoffile, destination] = uigetfile(picType);
            fulldestination = [destination,nameoffile];
            
            if ischar(destination) % check if exist destination is a char array
                im = imread(fulldestination);
                imshow(im, 'parent', app.UIAxes);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            currfig = figure;
            
            CurrentImage = (app.UIAxes);
            im = imrotate(CurrentImage,eval(value));
            imshow(im, 'parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseapictureDropDownLabel = uilabel(app.UIFigure);
            app.ChooseapictureDropDownLabel.Text = 'Choose a picture';
            app.ChooseapictureDropDown = uidropdown(app.UIFigure);
            app.ChooseapictureDropDown.Items = {'Peppers', 'Corn', 'Bag', 'Pears ', 'Spine', 'Strawberries', 'Tape'};
            app.ChooseapictureDropDown.ItemsData = {'peppers.png', 'corn.tif', 'bag.png', 'pears.png', 'spine.tif', 'strawberries.jpg', 'tape.png'};
            app.ChooseapictureDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseapictureDropDownValueChanged, true);
            app.ChooseapictureDropDown.Value = 'peppers.png';
            app.WindowsizeofGaussianKernelSliderLabel = uilabel(app.UIFigure);
            app.WindowsizeofGaussianKernelSliderLabel.Text = 'Window size of Gaussian Kernel';
            app.WindowsizeofGaussianKernelSlider = uislider(app.UIFigure);
            app.SigmaforGaussianKernelSliderLabel = uilabel(app.UIFigure);
            app.SigmaforGaussianKernelSliderLabel.Text = 'Sigma for Gaussian Kernel';
            app.SigmaforGaussianKernelSlider = uislider(app.UIFigure);
            app.rotatethefinalfilterkernelbyagivenangleSliderLabel = uilabel(app.UIFigure);
            app.rotatethefinalfilterkernelbyagivenangleSliderLabel.Text = 'rotate the final filter kernel by a given angle';
            app.rotatethefinalfilterkernelbyagivenangleSlider = uislider(app.UIFigure);
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scratch_Lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end